classdef MHRA_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputFileMenu                   matlab.ui.container.Menu
        HazardInformationMenu           matlab.ui.container.Menu
        ComponentFragilityInformationMenu  matlab.ui.container.Menu
        SystemEquationInformationMenu   matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        NumberofSamplesforEachIntensityMenu  matlab.ui.container.Menu
        SolverMenu                      matlab.ui.container.Menu
        BooleanAlgebraUMBMenu           matlab.ui.container.Menu
        OrginalDQFMMenu                 matlab.ui.container.Menu
        ImprovedDQFMMenu                matlab.ui.container.Menu
        ResultsMenu                     matlab.ui.container.Menu
        MultiHazardSurfaceMenu          matlab.ui.container.Menu
        ComponentFragilitySurfacesMenu  matlab.ui.container.Menu
        SystemFragilitySurfacesMenu     matlab.ui.container.Menu
        SystemRiskResultBarMenu         matlab.ui.container.Menu
        SystemRiskResultTableMenu       matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        HazardFragilityInformationLabel  matlab.ui.control.Label
        HazardInformationLabel          matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        FragilityInputXLSButton         matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        HazardInputXLSButton            matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        MultiHazardSurfacesTab          matlab.ui.container.Tab
        MultiHazardSurfaceLabel         matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        ComponentFragilitySurfacesTab   matlab.ui.container.Tab
        ComponentFragilitySurfaceformainSSCLabel  matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        SystemFragilitySurfaceResultsTab  matlab.ui.container.Tab
        SystemFragilitySurfaceResultsLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        SystemRiskResultBarTab          matlab.ui.container.Tab
        SystemRiskResultBarLabel        matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        SystemRiskResultTable           matlab.ui.container.Tab
        SystemRiskResultTableLabel      matlab.ui.control.Label
        UITable4                        matlab.ui.control.Table
        RightPanel                      matlab.ui.container.Panel
        SamplingApproachonlyforDQFMButtonGroup  matlab.ui.container.ButtonGroup
        quasiMCSButton                  matlab.ui.control.RadioButton
        LHSButton                       matlab.ui.control.RadioButton
        CrudeMCSButton                  matlab.ui.control.RadioButton
        SolverPanel                     matlab.ui.container.Panel
        BooleanAlgebraUMBButton         matlab.ui.control.Button
        ImprovedDQFMScalingButton       matlab.ui.control.Button
        OriginalDQFMButton              matlab.ui.control.Button
        ofSamplesforEachIntensityEditField  matlab.ui.control.NumericEditField
        ofSamplesforEachIntensityEditFieldLabel  matlab.ui.control.Label
        SolverSettingLabel              matlab.ui.control.Label
        SystemEquationInformationLabel  matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        SystemEquationXLSButton         matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        PGA_I % PGA Initial
        PGA_L % PGA Last
        PGA_S % PGA Subdivision
        NSEP  % Number of Samples for each PGA

        PGA   % PGA vector
        H     % H vector
        PF_C  % Component Fragilities
        PF_S  % System Fragilities 
        Risk  % System Risk

        SM    % Sampling Method 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.PGA_I = app.IntensityInitalEditField.Value;
            % app.PGA_L = app.IntensityLastEditField.Value;
            % app.PGA_S = app.IntensitySubdivisionEditField.Value;
            app.NSEP = app.ofSamplesforEachIntensityEditField.Value;
            selectedButton = app.SamplingApproachonlyforDQFMButtonGroup.SelectedObject;
            app.SM = selectedButton.Text;
        end

        % Callback function: HazardInformationMenu, HazardInputXLSButton
        function HazardInputXLSButtonPushed(app, event)
            [filename,path]=uigetfile();
            addpath(path);
            t1 =  readtable(filename,'Sheet',1);
            app.UITable.Data = t1;
        end

        % Callback function: ComponentFragilityInformationMenu, 
        % ...and 1 other component
        function FragilityInputXLSButtonPushed(app, event)
           [filename,path]=uigetfile();
           addpath(path);
           t2 =  readtable(filename,'Sheet',2);
           app.UITable2.Data = t2;
        end

        % Callback function: SystemEquationInformationMenu, 
        % ...and 1 other component
        function SytemEquationXLSButtonPushed(app, event)
           [filename,path]=uigetfile();
           addpath(path);
           t3 = readtable(filename,'Sheet',3); 
           app.UITable3.Data = t3;
        end

        % Callback function: OrginalDQFMMenu, OriginalDQFMButton
        function OriginalDQFMButtonPushed(app, event)
            t1 = app.UITable.Data;
            t2 = app.UITable2.Data;
            t3 = app.UITable3.Data;
            % PGA = [app.PGA_I:app.PGA_S:app.PGA_L]';
            
            %% --
            % Input Multi-Hazard Data
            Hm  = t1{2:end,2:end};
            PGA = t1{1,2:end};
            H   = t1{2:end,1}';

            % Input Multi-Fragility Data
            n_var = sum(t2{:,end} == 0);
            n_rf  = length(t2{:,end})-n_var;
            ns = size(t3,1);  % a total number of scenarios

            % Seismic hazard
            Ams = t2{1:n_var,2}';
            brs = t2{1:n_var,3}';
            bus = t2{1:n_var,4}';
            bcs = (brs.^2+bus.^2).^(1/2);
            % Decompose bc to bRcs and bCcs
            bRcs = (bcs.^2/2).^(1/2);
            bCcs = (bcs.^2/2).^(1/2);

            % Tsunami hazard
            Amt = t2{1:n_var,5}';
            brt = t2{1:n_var,6}';
            but = t2{1:n_var,7}';
            bct = (brt.^2+but.^2).^(1/2);
            % Decompose bc to bRct and bCct
            bRct = (bct.^2/2).^(1/2);
            bCct = (bct.^2/2).^(1/2);

            % Define correlation
            rho = 0;  % 0.7; 0.99999; 0;

            % Random failure
            rf = t2{n_var+1:end,8};

            % Make multi-hazard intensity domain
            [X,Y] = meshgrid(PGA,H);

            % Extract samples using MCS
            % N = 1e4;
            N = app.NSEP; %1e3;
            
tic
            for j = 1:length(PGA)
                j
                for k = 1:length(H)
                    
                    % Seismic Response
                    Rms = Ams;
                    n_var  = length(Rms);
                    Rscov  = eye(length(Rms));

                    switch app.SM
                        case 'Crude MCS'
                            RsSamples = mvnrnd(zeros(n_var,1),Rscov,N);
                        case 'LHS'
                            RsSamples = lhsnorm(zeros(n_var,1),Rscov,N);
                        case 'quasi-MCS'
                            % RsSamples = lhsnorm(zeros(n_var,1),Rscov,N)
                            p = sobolset(n_var,'Skip',1e3,'Leap',1e2); %skip the first 1000 values, and then retain every 101st point
                            p = scramble(p,'MatousekAffineOwen'); %MatousekAffineOwen scramling strategy. For no scrambling disable this line.
                            u = p(1:N,:);
                            for i1 = 1:n_var
                                RsSamples(:,i1) = icdf('Normal',u(:,i1),0,1);
                            end
                    end

                    for i = 1:n_var
                        RsSamples(:,i) = (PGA(j)/Rms(i)*Rms(i))*exp(RsSamples(:,i)*bRcs(i));
                    end

                    % Seismic Capacity
                    Cms = Ams;
                    n_var  = length(Cms);
                    Cscov  = eye(length(Cms));
                    switch app.SM
                        case 'Crude MCS'
                            [CsSamples] = mvnrnd(zeros(n_var,1),Cscov,N);
                        case 'LHS'
                            [CsSamples] = lhsnorm(zeros(n_var,1),Cscov,N);
                        case 'quasi-MCS'
                            % [CsSamples] = lhsnorm(zeros(n_var,1),Cscov,N);
                            p = sobolset(n_var,'Skip',1e3,'Leap',1e2); %skip the first 1000 values, and then retain every 101st point
                            p = scramble(p,'MatousekAffineOwen'); %MatousekAffineOwen scramling strategy. For no scrambling disable this line.
                            u = p(1:N,:);
                            for i1 = 1:n_var
                                CsSamples(:,i1) = icdf('Normal',u(:,i1),0,1);
                            end
                    end

                    for i = 1:n_var
                        CsSamples(:,i) = Cms(i)*exp(CsSamples(:,i)*bCcs(i));
                    end

                    % Tsunami Response
                    Rmt = Amt;
                    n_var  = length(Rmt);
                    Rtcov  = eye(length(Rmt));
                    switch app.SM
                        case 'Crude MCS'
                            RtSamples = mvnrnd(zeros(n_var,1),Rtcov,N);
                        case 'LHS'
                            RtSamples = lhsnorm(zeros(n_var,1),Rtcov,N);
                        case 'quasi-MCS'
                            % RtSamples = lhsnorm(zeros(n_var,1),Rtcov,N);
                            p = sobolset(n_var,'Skip',1e3,'Leap',1e2); %skip the first 1000 values, and then retain every 101st point
                            p = scramble(p,'MatousekAffineOwen'); %MatousekAffineOwen scramling strategy. For no scrambling disable this line.
                            u = p(1:N,:);
                            for i1 = 1:n_var
                                RtSamples(:,i1) = icdf('Normal',u(:,i1),0,1);
                            end
                    end

                    for i = 1:n_var
                        RtSamples(:,i) = (H(k)/Rmt(i)*Rmt(i))*exp(RtSamples(:,i)*bRct(i));
                    end

                    % Tsunami Capacity
                    Cmt = Amt;
                    n_var  = length(Cmt);
                    Ctcov  = eye(length(Cmt));
                    switch app.SM
                        case 'Crude MCS'
                            [CtSamples] = mvnrnd(zeros(n_var,1),Ctcov,N);
                        case 'LHS'
                            [CtSamples] = lhsnorm(zeros(n_var,1),Ctcov,N);
                        case 'quasi-MCS'
                            % [CtSamples] = lhsnorm(zeros(n_var,1),Ctcov,N);
                            p = sobolset(n_var,'Skip',1e3,'Leap',1e2); %skip the first 1000 values, and then retain every 101st point
                            p = scramble(p,'MatousekAffineOwen'); %MatousekAffineOwen scramling strategy. For no scrambling disable this line.
                            u = p(1:N,:);
                            for i1 = 1:n_var
                                CtSamples(:,i1) = icdf('Normal',u(:,i1),0,1);
                            end
                    end

                    for i = 1:n_var
                        CtSamples(:,i) = Cmt(i)*exp(CtSamples(:,i)*bCct(i));
                    end

                    % -- Random failures --
                    for ii = 1:n_rf
                        for jj = 1:N
                            RF(jj,ii) = binornd(1,rf(ii));   % Extract '0'(safe) or '1'(failure) samples using Binomial distribution
                        end
                    end

                    % -- Evaluate wheter component failure occurs or not --
                    for i = 1:n_var
                        Cs(:,i) =  RsSamples(:,i) > CsSamples(:,i); % Seismic only
                        Ct(:,i) =  RtSamples(:,i) > CtSamples(:,i); % Tsunami only
                        C(:,i) =  Cs(:,i) | Ct(:,i);  % Seismic & Tsunami
                    end

                    % -- Systems Analyses --
                    for i = 1:ns
                        S(:,i) = eval(char(t3{i,2}));
                    end

                    % -- Evaluate Failure Probability for Components --
                    for i = 1:n_var
                        pfC(k,j,i) = sum(C(:,i))/N; %length(find (RSamples(:,1) > CSamples(:,1)))/N;
                    end

                    % -- Evaluate Failure Probability for Systems --
                    for i = 1:ns
                        pfS(k,j,i) = sum(S(:,i))/N;
                    end

                end
            end
         
            %% -- Risk Convolution
            for i=1:ns
                R(i,:) = Risk2Dd(PGA,H,pfS(:,:,i),Hm);
            end
toc
            app.PGA = PGA;
            app.H   = H;
            app.PF_C = pfC;   % Component Fragilities
            app.PF_S = pfS;   % System Fragilities
            app.Risk = R;     % System Risk

            % Figure : Multi-Hazard surfaces
            surf(app.UIAxes3,X,Y,Hm); xlabel('PGA(g)'); ylabel('H(m)'); zlabel('AFE(/yr)');
            title(app.UIAxes3,'Multi-hazard surface');

            % Figure : System multi-hazard fragility surfaces
            contour(app.UIAxes,X,Y,pfS(:,:,end),'ShowText','on'); xlabel('PGA(g)'); ylabel('H(m)'); zlabel('Pf');
            title(app.UIAxes,'System fragility surface (end-scenario: CM)');

            % Figure : Component multi-hazard fragility surfaces
            surf(app.UIAxes4,X,Y,pfC(:,:,1)); xlabel('PGA(g)'); ylabel('H(m)');  zlabel('Pf');
            title(app.UIAxes4,'Component fragility surface (major equipment)');

            % Table : System Risk Table
            SRT = t3{:,1};
            SET = table2array(t3(:,4)); % Secondary ET

            for i=1:length(R)
                SRT{i,2} = R(i);
                SRT{i,3} = R(i)*SET(i);
                % CDFc(i,:) =  R(i)*SET(i);
            end

            format shortEng
            format compact
            app.UITable4.Data = cell2table(SRT);

            % Figure : System Seismic Risk Curve
            test = t3{:,1}';
            X = categorical(test);
            X = reordercats(X,test);
            bar(app.UIAxes2,X,[R]);
            text(app.UIAxes2,1:length([R]),[R],num2str([R]),'vert','bottom','horiz','center');
            %% --
            close figure 1

            function [Risk] = Risk2Dd(PGA,H,S,Hm)

                % This function aims to integrate original 2-D hazard curve with original
                % fragility curve from data perspective.

                % Input Data Format
                % PGA : Vector      "original hazard data x domain"
                % H   : Vector      "original hazard data y domain"
                % Hm  : Matrix      "original hazard data z domain"
                % S   : Matrix      "original fragilit data"
                % Note : PGA and H components should be "no zero" because this is log-log data linear interpolation.


                % Original hazard curve in log-log-log space
                [X,Y] = meshgrid(PGA,H);
                Z = Hm;

                % Calculate dHm: "mixed derivative theorem (forward algorithm)"
                Hmt = zeros(length(H)+1,length(PGA)+1);  % for derivative, last column and last row -> Zero
                Hmt(1:(length(H)),1:(length(PGA))) = Hm;
                for i1 = 1:length(PGA)
                    for j1 = 1:length(H)
                        dHm(j1,i1) = Hmt(j1,i1) -Hmt(j1+1,i1) -Hmt(j1,i1+1) +Hmt(j1+1,i1+1);
                    end
                end

                % Risk convolution
                dR = S.*dHm;
                Risk = sum(sum(dR));
            end

        end

        % Callback function: NumberofSamplesforEachIntensityMenu, 
        % ...and 1 other component
        function ofSamplesforEachPGAEditFieldValueChanged(app, event)
            app.NSEP = app.ofSamplesforEachIntensityEditField.Value;
        end

        % Callback function: SystemFragilitySurfacesMenu, UIAxes
        function UIAxesButtonDown(app, event)
            PGA = app.PGA;
            pfS = app.PF_S;
            % plot(app.UIAxes,PGA,pfS);legend(app.UIAxes,t3{:,1}');
        end

        % Callback function
        function UIAxes2ButtonDown(app, event)
            t3 = app.UITable3.Data;
            R = app.Risk;
            X = categorical(t3{:,1}');
            X = reordercats(X,t3{:,1}');
            bar(app.UIAxes2,X,R);
            text(app.UIAxes2,1:length(R),R,num2str(R),'vert','bottom','horiz','center'); 
        end

        % Callback function: ImprovedDQFMMenu, ImprovedDQFMScalingButton
        function ImprovedDQFMScalingButtonPushed(app, event)
            t1 = app.UITable.Data;
            t2 = app.UITable2.Data;
            t3 = app.UITable3.Data;
            % PGA = [app.PGA_I:app.PGA_S:app.PGA_L]';
            
            % Input Multi-Hazard Data
            Hm  = t1{2:end,2:end};
            PGA = t1{1,2:end};
            H   = t1{2:end,1}';

            % Input Multi-Fragility Data
            n_var = sum(t2{:,end} == 0);
            n_rf  = length(t2{:,end})-n_var;
            ns = size(t3,1);  % a total number of scenarios

            % Seismic hazard
            Ams = t2{1:n_var,2}';
            brs = t2{1:n_var,3}';
            bus = t2{1:n_var,4}';
            bcs = (brs.^2+bus.^2).^(1/2);
            % Decompose bc to bRcs and bCcs
            bRcs = (bcs.^2/2).^(1/2);
            bCcs = (bcs.^2/2).^(1/2);

            % Tsunami hazard
            Amt = t2{1:n_var,5}';
            brt = t2{1:n_var,6}';
            but = t2{1:n_var,7}';
            bct = (brt.^2+but.^2).^(1/2);
            % Decompose bc to bRct and bCct
            bRct = (bct.^2/2).^(1/2);
            bCct = (bct.^2/2).^(1/2);

            % Define correlation
            rho = 0;  % 0.7; 0.99999; 0;

            % Random failure
            rf = t2{n_var+1:end,8};

            % Make multi-hazard intensity domain
            [X,Y] = meshgrid(PGA,H);

            % Extract samples using MCS
            N = 1e4;
tic
            % -- Seismic Response --
            Rms = Ams;   % [Am Am];
            n_var  = length(Rms);
            Rscov  = eye(length(Rms));
            switch app.SM
                case 'Crude MCS'
                    RsSamples_org = mvnrnd(zeros(n_var,1),Rscov,N);
                case 'LHS'
                    RsSamples_org = lhsnorm(zeros(n_var,1),Rscov,N);
                case 'quasi-MCS'
                    % RsSamples_org = lhsnorm(zeros(n_var,1),Rscov,N);
                    p = sobolset(n_var,'Skip',1e3,'Leap',1e2); %skip the first 1000 values, and then retain every 101st point
                    p = scramble(p,'MatousekAffineOwen'); %MatousekAffineOwen scramling strategy. For no scrambling disable this line.
                    u = p(1:N,:);
                    for i1 = 1:n_var
                        RsSamples_org(:,i1) = icdf('Normal',u(:,i1),0,1);
                    end
            end

            for i = 1:n_var
                RsSamples_org(:,i) = Rms(i)*exp(RsSamples_org(:,i)*bRcs(i));
            end

            % -- Seismic Capacity --
            Cms = Ams;   % [Am Am];
            n_var  = length(Cms);
            Cscov  = eye(length(Cms));
            switch app.SM
                case 'Crude MCS'
                    [CsSamples] = mvnrnd(zeros(n_var,1),Cscov,N);
                case 'LHS'
                    CsSamples = lhsnorm(zeros(n_var,1),Cscov,N);
                case 'quasi-MCS'
                    % CsSamples = lhsnorm(zeros(n_var,1),Cscov,N);
                    p = sobolset(n_var,'Skip',1e3,'Leap',1e2); %skip the first 1000 values, and then retain every 101st point
                    p = scramble(p,'MatousekAffineOwen'); %MatousekAffineOwen scramling strategy. For no scrambling disable this line.
                    u = p(1:N,:);
                    for i1 = 1:n_var
                        CsSamples(:,i1) = icdf('Normal',u(:,i1),0,1);
                    end
            end

            for i = 1:n_var
                CsSamples(:,i) = Cms(i)*exp(CsSamples(:,i)*bCcs(i));
            end

            % -- Tsunami Response Response --
            Rmt = Amt;   % [Am Am];
            n_var  = length(Rmt);
            Rtcov  = eye(length(Rmt));
            switch app.SM
                case 'Crude MCS'
                    RtSamples_org = mvnrnd(zeros(n_var,1),Rtcov,N);
                case 'LHS'
                    RtSamples_org = lhsnorm(zeros(n_var,1),Rtcov,N);
                case 'quasi-MCS'
                    % RtSamples_org = lhsnorm(zeros(n_var,1),Rtcov,N);
                    p = sobolset(n_var,'Skip',1e3,'Leap',1e2); %skip the first 1000 values, and then retain every 101st point
                    p = scramble(p,'MatousekAffineOwen'); %MatousekAffineOwen scramling strategy. For no scrambling disable this line.
                    u = p(1:N,:);
                    for i1 = 1:n_var
                        RtSamples_org(:,i1) = icdf('Normal',u(:,i1),0,1);
                    end
            end

            for i = 1:n_var
                RtSamples_org(:,i) = Rmt(i)*exp(RtSamples_org(:,i)*bRct(i));
            end

            % -- Tsunami Capacity --
            Cmt = Amt;   % [Am Am];
            n_var  = length(Cmt);
            Ctcov  = eye(length(Cmt));
            switch app.SM
                case 'Crude MCS'
                    [CtSamples] = mvnrnd(zeros(n_var,1),Ctcov,N);
                case 'LHS'
                    CtSamples = lhsnorm(zeros(n_var,1),Ctcov,N);
                case 'quasi-MCS'
                    % CtSamples = lhsnorm(zeros(n_var,1),Ctcov,N);
                    p = sobolset(n_var,'Skip',1e3,'Leap',1e2); %skip the first 1000 values, and then retain every 101st point
                    p = scramble(p,'MatousekAffineOwen'); %MatousekAffineOwen scramling strategy. For no scrambling disable this line.
                    u = p(1:N,:);
                    for i1 = 1:n_var
                        CtSamples(:,i1) = icdf('Normal',u(:,i1),0,1);
                    end
            end

            for i = 1:n_var
                CtSamples(:,i) = Cmt(i)*exp(CtSamples(:,i)*bCct(i));
            end

            % -- Random failures --
            for i = 1:n_rf
                for j = 1:N
                    RF(j,i) = binornd(1,rf(i));   % Extract '0'(safe) or '1'(failure) samples using Binomial distribution
                end
            end

            for j = 1:length(PGA)
                j
                for k = 1:length(H)

                    % -- Scale Seismic Response under linear assumption --
                    for i = 1:n_var
                        RsSamples(:,i) = RsSamples_org(:,i)*(PGA(j)/Ams(i)); % Scale according to seismic intensity
                    end

                    % -- Scale Tsunami Response under linear assumption --
                    for i = 1:n_var
                        RtSamples(:,i) = RtSamples_org(:,i)*(H(k)/Amt(i)); % Scale according to seismic intensity
                    end

                    % -- Evaluate wheter component failure occurs or not --
                    for i = 1:n_var
                        Cs(:,i) =  RsSamples(:,i) > CsSamples(:,i); % Seismic only
                        Ct(:,i) =  RtSamples(:,i) > CtSamples(:,i); % Tsunami only
                        C(:,i) =  Cs(:,i) | Ct(:,i);  % Seismic & Tsunami
                    end

                    % -- Systems Analyses --
                    for i = 1:ns
                        S(:,i) = eval(char(t3{i,2}));
                    end

                    % -- Evaluate Failure Probability for Components --
                    for i = 1:n_var
                        pfC(k,j,i) = sum(C(:,i))/N; %length(find (RSamples(:,1) > CSamples(:,1)))/N;
                    end

                    % -- Evaluate Failure Probability for Systems --
                    for i = 1:ns
                        pfS(k,j,i) = sum(S(:,i))/N;
                    end

                end
            end

            %% -- Risk Convolution
            for i=1:ns
                R(i,:) = Risk2Dd(PGA,H,pfS(:,:,i),Hm);
            end
toc
            app.PGA = PGA;
            app.H   = H;
            app.PF_C = pfC;   % Component Fragilities
            app.PF_S = pfS;   % System Fragilities
            app.Risk = R;     % System Risk

            % Figure : Multi-Hazard surfaces
            surf(app.UIAxes3,X,Y,Hm); xlabel('PGA(g)'); ylabel('H(m)'); zlabel('AFE(/yr)');
            title(app.UIAxes3,'Multi-hazard surface');

            % Figure : System multi-hazard fragility surfaces
            contour(app.UIAxes,X,Y,pfS(:,:,end),'ShowText','on'); xlabel('PGA(g)'); ylabel('H(m)'); zlabel('Pf');
            title(app.UIAxes,'System fragility surface (end-scenario: CM)');

            % Figure : Component multi-hazard fragility surfaces
            surf(app.UIAxes4,X,Y,pfC(:,:,1)); xlabel('PGA(g)'); ylabel('H(m)');  zlabel('Pf');
            title(app.UIAxes4,'Component fragility surface (major equipment)');


            % Table : System Risk Table
            SRT = t3{:,1};
            SET = table2array(t3(:,4)); % Secondary ET

            for i=1:length(R)
                SRT{i,2} = R(i);
                SRT{i,3} = R(i)*SET(i);
                % CDFc(i,:) =  R(i)*SET(i);
            end

            format shortEng
            format compact
            app.UITable4.Data = cell2table(SRT);

            % Figure : System Seismic Risk Curve
            test = t3{:,1}';
            X = categorical(test);
            X = reordercats(X,test);
            bar(app.UIAxes2,X,[R]);
            text(app.UIAxes2,1:length([R]),[R],num2str([R]),'vert','bottom','horiz','center');
            %%- 
            close figure 1

            function [Risk] = Risk2Dd(PGA,H,S,Hm)

                % This function aims to integrate original 2-D hazard curve with original
                % fragility curve from data perspective.

                % Input Data Format
                % PGA : Vector      "original hazard data x domain"
                % H   : Vector      "original hazard data y domain"
                % Hm  : Matrix      "original hazard data z domain"
                % S   : Matrix      "original fragilit data"
                % Note : PGA and H components should be "no zero" because this is log-log data linear interpolation.


                % Original hazard curve in log-log-log space
                [X,Y] = meshgrid(PGA,H);
                Z = Hm;

                % Calculate dHm: "mixed derivative theorem (forward algorithm)"
                Hmt = zeros(length(H)+1,length(PGA)+1);  % for derivative, last column and last row -> Zero
                Hmt(1:(length(H)),1:(length(PGA))) = Hm;
                for i1 = 1:length(PGA)
                    for j1 = 1:length(H)
                        dHm(j1,i1) = Hmt(j1,i1) -Hmt(j1+1,i1) -Hmt(j1,i1+1) +Hmt(j1+1,i1+1);
                    end
                end

                % Risk convolution
                dR = S.*dHm;
                Risk = sum(sum(dR));
            end
        end

        % Callback function: MultiHazardSurfaceMenu, UIAxes3
        function UIAxes3ButtonDown(app, event)
            t1 = app.UITable.Data;
            % Input Multi-Hazard Data
            Hm  = t1{2:end,2:end};
            PGA = t1{1,2:end};
            H   = t1{2:end,1}';
            % Figure : Multi-Hazard surfaces
            surf(app.UIAxes3,X,Y,Hm); xlabel('PGA(g)'); ylabel('H(m)'); zlabel('AFE(/yr)');
            title(app.UIAxes3,'Multi-hazard surface');

        end

        % Callback function: ComponentFragilitySurfacesMenu, UIAxes4
        function UIAxes4ButtonDown(app, event)
            
        end

        % Callback function: SystemRiskResultTableMenu, UITable4
        function UITable4CellEdit(app, event)

            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Callback function: BooleanAlgebraUMBButton, BooleanAlgebraUMBMenu
        function BooleanAlgebraUMBButtonPushed(app, event)
            t1 = app.UITable.Data;
            t2 = app.UITable2.Data;
            t3 = app.UITable3.Data;

            % Input Multi-Hazard Data
            Hm  = t1{2:end,2:end};
            PGA = t1{1,2:end};
            H   = t1{2:end,1}';

            % Input Multi-Fragility Data
            n_var = sum(t2{:,end} == 0);
            n_rf  = length(t2{:,end})-n_var;
            
            % Seismic hazard
            Ams = t2{1:n_var,2}';
            brs = t2{1:n_var,3}';
            bus = t2{1:n_var,4}';
            bcs = (brs.^2+bus.^2).^(1/2);
            
            % Tsunami hazard
            Amt = t2{1:n_var,5}';
            brt = t2{1:n_var,6}';
            but = t2{1:n_var,7}';
            bct = (brt.^2+but.^2).^(1/2);
      
            % Random failure
            rf = t2{n_var+1:end,8};
tic
            % Generate 3-D Seismic and Tsunami dimention space
            [X,Y] = meshgrid(PGA,H);
            % Evaluate Seismic and Tsunami multi-fragility curve for each component
            % -> "OR" relationship between two hazard failure modes
            for i = 1:length(PGA)
                pga = PGA(i);
                for j = 1:length(H)
                    h = H(j);
                    for k = 1:n_var
                        cs = normcdf(log(pga),log(Ams(k)), bcs(k));
                        ct = normcdf(log(h),log(Amt(k)), bct(k));
                        C(j,i,k) = 1 - (1 -cs)*(1 -ct);
                    end
                end
            end

            for i = 1:length(PGA)
                pga = PGA(i);
                for j = 1:length(H)
                    h = H(j);
                    for k = 1:n_rf
                        RF(j,i,k) = rf(k);
                    end
                end
            end


            %% -- Systems analysis using Boolean expression
            % Input System Scenarios(or Models)
            ns = size(t3,1);  % a total number of scenarios

            for i = 1:ns
                S(:,:,i) = eval(char(t3{i,3}));
            end

            %% -- Risk Convolution
            for i=1:ns
                R(i,:) = Risk2Dd(PGA,H,S(:,:,i),Hm);
            end
toc
            % -- Evaluate Failure Probability for Systems --
            pfC = C;
            pfS = S;

            app.PGA = PGA;
            app.H   = H;
            app.PF_C = pfC;   % Component Fragilities
            app.PF_S = pfS;   % System Fragilities
            app.Risk = R;     % System Risk

            % Figure : Multi-Hazard surfaces
            surf(app.UIAxes3,X,Y,Hm); xlabel(app.UIAxes3,'PGA(g)'); ylabel(app.UIAxes3,'H(m)'); zlabel(app.UIAxes3,'AFE(/yr)');
            title(app.UIAxes3,'Multi-hazard surface');

            % Figure : System multi-hazard fragility surfaces
            contour(app.UIAxes,X,Y,S(:,:,end),'ShowText','on'); xlabel(app.UIAxes,'PGA(g)'); ylabel(app.UIAxes,'H(m)'); zlabel(app.UIAxes,'Failure Probabilities');
            title(app.UIAxes,'System fragility surface (end-scenario risk: CM risk)');

            % Figure : Component multi-hazard fragility surfaces
            surf(app.UIAxes4,X,Y,C(:,:,1)); xlabel(app.UIAxes4,'PGA(g)'); ylabel(app.UIAxes4,'H(m)');zlabel(app.UIAxes4,'Pf');
            title(app.UIAxes4,'Component fragility surface (major equipment)');

            % Table : System Risk Table
            SRT = t3{:,1};
            SET = table2array(t3(:,4)); % Secondary ET

            for i=1:length(R)
                SRT{i,2} = R(i);
                SRT{i,3} = R(i)*SET(i);
                % CDFc(i,:) =  R(i)*SET(i);
            end
            % CDF = sum(CDFc);
            % SRT{length(R)+1,1} = 'CDF';
            % SRT{length(R)+1,2} = CDF;
            % SRT{length(R)+1,3} = CDF;

            format shortEng
            format compact
            app.UITable4.Data = cell2table(SRT);

            % Figure : System Seismic Risk Curve
            test = t3{:,1}';
            % test{1,length(R)+1} = 'CDF';
            X = categorical(test);
            X = reordercats(X,test);
            % bar(app.UIAxes2,X,[R; CDF]);
            bar(app.UIAxes2,X,[R]);
            text(app.UIAxes2,1:length([R]),[R],num2str([R]),'vert','bottom','horiz','center');

            function [Risk] = Risk2Dd(PGA,H,S,Hm)

                % This function aims to integrate original 2-D hazard curve with original
                % fragility curve from data perspective.

                % Input Data Format
                % PGA : Vector      "original hazard data x domain"
                % H   : Vector      "original hazard data y domain"
                % Hm  : Matrix      "original hazard data z domain"
                % S   : Matrix      "original fragilit data"
                % Note : PGA and H components should be "no zero" because this is log-log data linear interpolation.


                % Original hazard curve in log-log-log space
                [X,Y] = meshgrid(PGA,H);
                Z = Hm;

                % Calculate dHm: "mixed derivative theorem (forward algorithm)"
                Hmt = zeros(length(H)+1,length(PGA)+1);  % for derivative, last column and last row -> Zero
                Hmt(1:(length(H)),1:(length(PGA))) = Hm;
                for i1 = 1:length(PGA)
                    for j1 = 1:length(H)
                        dHm(j1,i1) = Hmt(j1,i1) -Hmt(j1+1,i1) -Hmt(j1,i1+1) +Hmt(j1+1,i1+1);
                    end
                end

                % Risk convolution
                dR = S.*dHm;
                Risk = sum(sum(dR));
            end

        end

        % Selection changed function: 
        % SamplingApproachonlyforDQFMButtonGroup
        function SamplingApproachonlyforDQFMButtonGroupSelectionChanged(app, event)
            selectedButton = app.SamplingApproachonlyforDQFMButtonGroup.SelectedObject;
            selectedButton.Text;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {533, 533, 533};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {533, 533};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {249, '1x', 279};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.InputFileMenu = uimenu(app.UIFigure);
            app.InputFileMenu.Text = 'Input File';
            app.HazardInformationMenu = uimenu(app.InputFileMenu);
            app.HazardInformationMenu.MenuSelectedFcn = createCallbackFcn(app, @HazardInputXLSButtonPushed, true);
            app.HazardInformationMenu.Text = 'Hazard Information';
            app.ComponentFragilityInformationMenu = uimenu(app.InputFileMenu);
            app.ComponentFragilityInformationMenu.MenuSelectedFcn = createCallbackFcn(app, @FragilityInputXLSButtonPushed, true);
            app.ComponentFragilityInformationMenu.Text = 'Component Fragility Information';
            app.SystemEquationInformationMenu = uimenu(app.InputFileMenu);
            app.SystemEquationInformationMenu.MenuSelectedFcn = createCallbackFcn(app, @SytemEquationXLSButtonPushed, true);
            app.SystemEquationInformationMenu.Text = 'System Equation Information';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.NumberofSamplesforEachIntensityMenu = uimenu(app.SettingsMenu);
            app.NumberofSamplesforEachIntensityMenu.MenuSelectedFcn = createCallbackFcn(app, @ofSamplesforEachPGAEditFieldValueChanged, true);
            app.NumberofSamplesforEachIntensityMenu.Text = 'Number of Samples for Each Intensity';
            app.SolverMenu = uimenu(app.SettingsMenu);
            app.SolverMenu.Text = 'Solver';
            app.BooleanAlgebraUMBMenu = uimenu(app.SolverMenu);
            app.BooleanAlgebraUMBMenu.MenuSelectedFcn = createCallbackFcn(app, @BooleanAlgebraUMBButtonPushed, true);
            app.BooleanAlgebraUMBMenu.Text = 'Boolean Algebra (UMB)';
            app.OrginalDQFMMenu = uimenu(app.SolverMenu);
            app.OrginalDQFMMenu.MenuSelectedFcn = createCallbackFcn(app, @OriginalDQFMButtonPushed, true);
            app.OrginalDQFMMenu.Text = 'Orginal DQFM';
            app.ImprovedDQFMMenu = uimenu(app.SolverMenu);
            app.ImprovedDQFMMenu.MenuSelectedFcn = createCallbackFcn(app, @ImprovedDQFMScalingButtonPushed, true);
            app.ImprovedDQFMMenu.Text = 'Improved DQFM';
            app.ResultsMenu = uimenu(app.UIFigure);
            app.ResultsMenu.Text = 'Results';
            app.MultiHazardSurfaceMenu = uimenu(app.ResultsMenu);
            app.MultiHazardSurfaceMenu.MenuSelectedFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.MultiHazardSurfaceMenu.Text = 'Multi-Hazard Surface';
            app.ComponentFragilitySurfacesMenu = uimenu(app.ResultsMenu);
            app.ComponentFragilitySurfacesMenu.MenuSelectedFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.ComponentFragilitySurfacesMenu.Text = 'Component Fragility Surfaces';
            app.SystemFragilitySurfacesMenu = uimenu(app.ResultsMenu);
            app.SystemFragilitySurfacesMenu.MenuSelectedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SystemFragilitySurfacesMenu.Text = 'System Fragility Surfaces';
            app.SystemRiskResultBarMenu = uimenu(app.ResultsMenu);
            app.SystemRiskResultBarMenu.Text = 'System Risk Result Bar';
            app.SystemRiskResultTableMenu = uimenu(app.ResultsMenu);
            app.SystemRiskResultTableMenu.MenuSelectedFcn = createCallbackFcn(app, @UITable4CellEdit, true);
            app.SystemRiskResultTableMenu.Text = 'System Risk Result Table';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Basic Input - 1';
            app.HazardInputXLSButton = uibutton(app.LeftPanel, 'push');
            app.HazardInputXLSButton.ButtonPushedFcn = createCallbackFcn(app, @HazardInputXLSButtonPushed, true);
            app.HazardInputXLSButton.Text = 'Hazard Input (XLS)';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Multi-hazard Data(I1-I2-AFE)'};
            app.UITable.RowName = {};
            app.FragilityInputXLSButton = uibutton(app.LeftPanel, 'push');
            app.FragilityInputXLSButton.ButtonPushedFcn = createCallbackFcn(app, @FragilityInputXLSButtonPushed, true);
            app.FragilityInputXLSButton.Text = 'Fragility Input (XLS)';
            app.UITable2 = uitable(app.LeftPanel);
            app.UITable2.ColumnName = {'Component'; 'Med1'; 'Beta1_r'; 'Beta1_u'; 'Med2'; 'Beta2_r'; 'Beta2_u'; 'RF'};
            app.UITable2.RowName = {};
            app.HazardInformationLabel = uilabel(app.LeftPanel);
            app.HazardInformationLabel.Text = '1. Hazard Information';
            app.HazardFragilityInformationLabel = uilabel(app.LeftPanel);
            app.HazardFragilityInformationLabel.Text = '2. Hazard Fragility Information';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Title = 'Output';
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.MultiHazardSurfacesTab = uitab(app.TabGroup);
            app.MultiHazardSurfacesTab.Title = 'Multi-Hazard Surfaces';
            app.UIAxes3 = uiaxes(app.MultiHazardSurfacesTab);
            xlabel(app.UIAxes3, 'PGA (g)')
            ylabel(app.UIAxes3, 'H (m)')
            zlabel(app.UIAxes3, 'Annual Frequency of Exceedance (/yr)')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.MultiHazardSurfaceLabel = uilabel(app.MultiHazardSurfacesTab);
            app.MultiHazardSurfaceLabel.Text = '<Multi-Hazard Surface>';
            app.ComponentFragilitySurfacesTab = uitab(app.TabGroup);
            app.ComponentFragilitySurfacesTab.Title = 'Component Fragility Surfaces';
            app.UIAxes4 = uiaxes(app.ComponentFragilitySurfacesTab);
            xlabel(app.UIAxes4, 'PGA (g)')
            ylabel(app.UIAxes4, 'H (m)')
            zlabel(app.UIAxes4, 'Probability of failures')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.ComponentFragilitySurfaceformainSSCLabel = uilabel(app.ComponentFragilitySurfacesTab);
            app.ComponentFragilitySurfaceformainSSCLabel.Text = '<Component Fragility Surface for main SSC>';
            app.SystemFragilitySurfaceResultsTab = uitab(app.TabGroup);
            app.SystemFragilitySurfaceResultsTab.Title = 'System Fragility Surface Results';
            app.UIAxes = uiaxes(app.SystemFragilitySurfaceResultsTab);
            xlabel(app.UIAxes, 'PGA (g)')
            ylabel(app.UIAxes, 'Probability of failures')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SystemFragilitySurfaceResultsLabel = uilabel(app.SystemFragilitySurfaceResultsTab);
            app.SystemFragilitySurfaceResultsLabel.Text = '<System Fragility Surface Results>';
            app.SystemRiskResultBarTab = uitab(app.TabGroup);
            app.SystemRiskResultBarTab.Title = 'System Risk Result Bar';
            app.UIAxes2 = uiaxes(app.SystemRiskResultBarTab);
            xlabel(app.UIAxes2, 'System Equations')
            ylabel(app.UIAxes2, 'Risk (/yr)')
            zlabel(app.UIAxes2, 'Z')
            app.SystemRiskResultBarLabel = uilabel(app.SystemRiskResultBarTab);
            app.SystemRiskResultBarLabel.Text = '<System Risk Result Bar>';
            app.SystemRiskResultTable = uitab(app.TabGroup);
            app.SystemRiskResultTable.Title = 'System Risk Result Table';
            app.UITable4 = uitable(app.SystemRiskResultTable);
            app.UITable4.ColumnName = {'Name'; 'Risk(/yr)'; 'CDF contribution(/yr)'};
            app.UITable4.RowName = {};
            app.UITable4.ColumnEditable = true;
            app.UITable4.CellEditCallback = createCallbackFcn(app, @UITable4CellEdit, true);
            app.SystemRiskResultTableLabel = uilabel(app.SystemRiskResultTable);
            app.SystemRiskResultTableLabel.Text = '<System Risk Result Table>';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Basic Input-2';
            app.SystemEquationXLSButton = uibutton(app.RightPanel, 'push');
            app.SystemEquationXLSButton.ButtonPushedFcn = createCallbackFcn(app, @SytemEquationXLSButtonPushed, true);
            app.SystemEquationXLSButton.Text = 'System Equation (XLS)';
            app.UITable3 = uitable(app.RightPanel);
            app.UITable3.ColumnName = {'Name'; 'System Equations(Logic)'; 'System Equations(UMB)'; 'Seconary ET'};
            app.UITable3.RowName = {};
            app.SystemEquationInformationLabel = uilabel(app.RightPanel);
            app.SystemEquationInformationLabel.Text = '3. System Equation Information';
            app.SolverSettingLabel = uilabel(app.RightPanel);
            app.SolverSettingLabel.Text = '4. Solver Setting';
            app.ofSamplesforEachIntensityEditFieldLabel = uilabel(app.RightPanel);
            app.ofSamplesforEachIntensityEditFieldLabel.Text = '# of Samples for Each Intensity';
            app.ofSamplesforEachIntensityEditField = uieditfield(app.RightPanel, 'numeric');
            app.ofSamplesforEachIntensityEditField.ValueDisplayFormat = '%.0f';
            app.ofSamplesforEachIntensityEditField.ValueChangedFcn = createCallbackFcn(app, @ofSamplesforEachPGAEditFieldValueChanged, true);
            app.SolverPanel = uipanel(app.RightPanel);
            app.SolverPanel.Title = 'Solver';
            app.OriginalDQFMButton = uibutton(app.SolverPanel, 'push');
            app.OriginalDQFMButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalDQFMButtonPushed, true);
            app.OriginalDQFMButton.Text = 'Original DQFM';
            app.ImprovedDQFMScalingButton = uibutton(app.SolverPanel, 'push');
            app.ImprovedDQFMScalingButton.ButtonPushedFcn = createCallbackFcn(app, @ImprovedDQFMScalingButtonPushed, true);
            app.ImprovedDQFMScalingButton.Text = 'Improved DQFM(Scaling)';
            app.BooleanAlgebraUMBButton = uibutton(app.SolverPanel, 'push');
            app.BooleanAlgebraUMBButton.ButtonPushedFcn = createCallbackFcn(app, @BooleanAlgebraUMBButtonPushed, true);
            app.BooleanAlgebraUMBButton.Text = 'Boolean Algebra (UMB)';
            app.SamplingApproachonlyforDQFMButtonGroup = uibuttongroup(app.RightPanel);
            app.SamplingApproachonlyforDQFMButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SamplingApproachonlyforDQFMButtonGroupSelectionChanged, true);
            app.SamplingApproachonlyforDQFMButtonGroup.Title = 'Sampling Approach (only for DQFM)';
            app.CrudeMCSButton = uiradiobutton(app.SamplingApproachonlyforDQFMButtonGroup);
            app.CrudeMCSButton.Text = 'Crude MCS';
            app.CrudeMCSButton.Value = true;
            app.LHSButton = uiradiobutton(app.SamplingApproachonlyforDQFMButtonGroup);
            app.LHSButton.Text = 'LHS';
            app.quasiMCSButton = uiradiobutton(app.SamplingApproachonlyforDQFMButtonGroup);
            app.quasiMCSButton.Text = 'quasi-MCS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MHRA_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end